classdef Chess_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UR5JointControlPanel    matlab.ui.container.Panel
        UR5_q1text              matlab.ui.control.TextArea
        UR5_q2text              matlab.ui.control.TextArea
        UR5_q3text              matlab.ui.control.TextArea
        UR5_q4text              matlab.ui.control.TextArea
        UR5_q5text              matlab.ui.control.TextArea
        UR5_q6text              matlab.ui.control.TextArea
        q1SliderLabel           matlab.ui.control.Label
        UR5_q1Slider            matlab.ui.control.Slider
        q2Label                 matlab.ui.control.Label
        UR5_q2Slider            matlab.ui.control.Slider
        q3Label                 matlab.ui.control.Label
        UR5_q3Slider            matlab.ui.control.Slider
        q4Label                 matlab.ui.control.Label
        UR5_q4Slider            matlab.ui.control.Slider
        q5Label                 matlab.ui.control.Label
        UR5_q5Slider            matlab.ui.control.Slider
        q6Label                 matlab.ui.control.Label
        UR5_q6Slider            matlab.ui.control.Slider
        i5JointControlPanel     matlab.ui.container.Panel
        i5_q1text               matlab.ui.control.TextArea
        i5_q2text               matlab.ui.control.TextArea
        i5_q3text               matlab.ui.control.TextArea
        i5_q4text               matlab.ui.control.TextArea
        i5_q5text               matlab.ui.control.TextArea
        i5_q6text               matlab.ui.control.TextArea
        q1Slider_2Label         matlab.ui.control.Label
        i5_q1Slider             matlab.ui.control.Slider
        q2Slider_2Label         matlab.ui.control.Label
        i5_q2Slider             matlab.ui.control.Slider
        q3Slider_2Label         matlab.ui.control.Label
        i5_q3Slider             matlab.ui.control.Slider
        q4Slider_2Label         matlab.ui.control.Label
        i5_q4Slider             matlab.ui.control.Slider
        q5Slider_2Label         matlab.ui.control.Label
        i5_q5Slider             matlab.ui.control.Slider
        q6Slider_2Label         matlab.ui.control.Label
        i5_q6Slider             matlab.ui.control.Slider
        DefaultPhaseButton      matlab.ui.control.Button
        ClearEnvironmentButton  matlab.ui.control.Button
        ScenarioRunButton       matlab.ui.control.Button
        ESTOPButton             matlab.ui.control.StateButton
        RESTARTButton           matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        UR5bot = UR5arm; % UR5 arm
        i5bot = AUBOi5; %i5 arm
        
    end
    
    properties (Access = public)
%         Property2 % Description
    end
    
    methods (Access = private)
        
        function GetPos(app)
            %UR5 teach
            qCurrent_UR5 = rad2deg(app.UR5bot.model.getpos);
            %UR5 joints
            app.UR5_q1text.Value = num2str(qCurrent_UR5(1));
            app.UR5_q2text.Value = num2str(qCurrent_UR5(2));
            app.UR5_q3text.Value = num2str(qCurrent_UR5(3));
            app.UR5_q4text.Value = num2str(qCurrent_UR5(4));
            app.UR5_q5text.Value = num2str(qCurrent_UR5(5));
            app.UR5_q6text.Value = num2str(qCurrent_UR5(6));
            
            % i5 teach
            qCurrent_i5 = rad2deg(app.i5bot.model.getpos);
            %i5 Joints
            app.i5_q1text.Value = num2str(qCurrent_i5(1));
            app.i5_q2text.Value = num2str(qCurrent_i5(2));
            app.i5_q3text.Value = num2str(qCurrent_i5(3));
            app.i5_q4text.Value = num2str(qCurrent_i5(4));
            app.i5_q5text.Value = num2str(qCurrent_i5(5));
            app.i5_q6text.Value = num2str(qCurrent_i5(6));
        end
        
        function Def_Phase(app)
            Default_phase;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: UR5_q1Slider
        function UR5_q1SliderValueChanging(app, event)
            q1_UR5 = pi/180* event.Value;
            steps = 2;
            UR5_CurrentJoint = app.UR5bot.model.getpos;
            UR5_EndJoint = app.UR5bot.model.getpos;
            UR5_EndJoint(:,1) = q1_UR5;
            
            q_traj = jtraj(UR5_CurrentJoint,UR5_EndJoint,steps);
            q1 = lspb(UR5_CurrentJoint(1),q1_UR5,steps);
            q_traj(:,1) = q1;
            app.UR5bot.model.base = transl([-0.4,0.3,-0.03]);

            app.UR5bot.MoveRobot(q_traj);
            app.GetPos();            
        end

        % Value changing function: UR5_q2Slider
        function UR5_q2SliderValueChanging(app, event)
            q2_UR5 = pi/180* event.Value;
            steps = 2;
            UR5_CurrentJoint = app.UR5bot.model.getpos;
            UR5_EndJoint = app.UR5bot.model.getpos;
            UR5_EndJoint(:,2) = q2_UR5;
            
            q_traj = jtraj(UR5_CurrentJoint,UR5_EndJoint,steps);
            q2 = lspb(UR5_CurrentJoint(1),q2_UR5,steps);
            q_traj(:,2) = q2;
            app.UR5bot.model.base = transl([-0.4,0.3,-0.03]);

            app.UR5bot.MoveRobot(q_traj);
            app.GetPos();       
        end

        % Value changing function: UR5_q3Slider
        function UR5_q3SliderValueChanging(app, event)
            q3_UR5 = pi/180* event.Value;
            steps = 2;
            UR5_CurrentJoint = app.UR5bot.model.getpos;
            UR5_EndJoint = app.UR5bot.model.getpos;
            UR5_EndJoint(:,3) = q3_UR5;
            
            q_traj = jtraj(UR5_CurrentJoint,UR5_EndJoint,steps);
            q3 = lspb(UR5_CurrentJoint(3),q3_UR5,steps);
            q_traj(:,3) = q3;
            app.UR5bot.model.base = transl([-0.4,0.3,-0.03]);

            app.UR5bot.MoveRobot(q_traj);
            app.GetPos();           
        end

        % Value changing function: UR5_q4Slider
        function UR5_q4SliderValueChanging(app, event)
            q4_UR5 = pi/180* event.Value;
            steps = 2;
            UR5_CurrentJoint = app.UR5bot.model.getpos;
            UR5_EndJoint = app.UR5bot.model.getpos;
            UR5_EndJoint(:,4) = q4_UR5;
            
            q_traj = jtraj(UR5_CurrentJoint,UR5_EndJoint,steps);
            q4 = lspb(UR5_CurrentJoint(4),q4_UR5,steps);
            q_traj(:,4) = q4;
            app.UR5bot.model.base = transl([-0.4,0.3,-0.03]);

            app.UR5bot.MoveRobot(q_traj);
            app.GetPos();          
        end

        % Value changing function: UR5_q5Slider
        function UR5_q5SliderValueChanging(app, event)
            q5_UR5 = pi/180* event.Value;
            steps = 2;
            UR5_EndJoint = app.UR5bot.model.getpos;
            UR5_EndJoint(:,5) = q5_UR5;
            
            q_traj = jtraj(UR5_CurrentJoint,UR5_EndJoint,steps);
            q5 = lspb(UR5_CurrentJoint(5),q5_UR5,steps);
            q_traj(:,5) = q5;
            app.UR5bot.model.base = transl([-0.4,0.3,-0.03]);

            app.UR5bot.MoveRobot(q_traj);
            app.GetPos();           
        end

        % Value changing function: UR5_q6Slider
        function UR5_q6SliderValueChanging(app, event)
            q6_UR5 = pi/180* event.Value;
            steps = 2;
            UR5_CurrentJoint = app.UR5bot.model.getpos;
            UR5_EndJoint = app.UR5bot.model.getpos;
            UR5_EndJoint(:,6) = q6_UR5;
            
            q_traj = jtraj(UR5_CurrentJoint,UR5_EndJoint,steps);
            q6 = lspb(UR5_CurrentJoint(6),q6_UR5,steps);
            q_traj(:,6) = q6;
            app.UR5bot.model.base = transl([-0.4,0.3,-0.03]);

            app.UR5bot.MoveRobot(q_traj);
            app.GetPos();
        end

        % Value changing function: i5_q1Slider
        function i5_q1SliderValueChanging(app, event)
            q1_i5= pi/180* event.Value;
            steps = 2;
            i5_CurrentJoint = app.i5bot.model.getpos;
            i5_EndJoint = app.UR5bot.model.getpos;
            i5_EndJoint(:,1) = q1_i5;

            q_traj = jtraj(i5_CurrentJoint,i5_EndJoint,steps);
            q1 = lspb(i5_CurrentJoint(1),q1_i5,steps);
            q_traj(:,1) = q1;
            app.i5bot.model.base = transl([0.4,-0.3,-0.03]);

            app.i5bot.MoveRobot(q_traj);
            app.GetPos();
            
        end

        % Value changing function: i5_q2Slider
        function i5_q2SliderValueChanging(app, event)
            q2_i5 = pi/180* event.Value;
            steps = 2;
            i5_CurrentJoint = app.i5bot.model.getpos;
            i5_EndJoint = app.UR5bot.model.getpos;
            i5_EndJoint(:,2) = q2_i5;

            q_traj = jtraj(i5_CurrentJoint,i5_EndJoint,steps);
            q2 = lspb(i5_CurrentJoint(2),q2_i5,steps);
            q_traj(:,2) = q2;
            app.i5bot.model.base = transl([0.4,-0.3,-0.03]);

            app.i5bot.MoveRobot(q_traj);
            app.GetPos();
            
        end

        % Value changing function: i5_q3Slider
        function i5_q3SliderValueChanging(app, event)
            q3_i5 = pi/180* event.Value;
            steps = 2;
            i5_CurrentJoint = app.i5bot.model.getpos;
            i5_EndJoint = app.UR5bot.model.getpos;
            i5_EndJoint(:,3) = q3_i5;

            q_traj = jtraj(i5_CurrentJoint,i5_EndJoint,steps);
            q3 = lspb(i5_CurrentJoint(3),q3_i5,steps);
            q_traj(:,3) = q3;
            app.i5bot.model.base = transl([0.4,-0.3,-0.03]);

            app.i5bot.MoveRobot(q_traj);
            app.GetPos();
            
        end

        % Value changing function: i5_q4Slider
        function i5_q4SliderValueChanging(app, event)
            q4_i5 = pi/180* event.Value;
            steps = 2;
            i5_CurrentJoint = app.i5bot.model.getpos;
            i5_EndJoint = app.UR5bot.model.getpos;
            i5_EndJoint(:,4) = q4_i5;

            q_traj = jtraj(i5_CurrentJoint,i5_EndJoint,steps);
            q4 = lspb(i5_CurrentJoint(4),q4_i5,steps);
            q_traj(:,4) = q4;
            app.i5bot.model.base = transl([0.4,-0.3,-0.03]);

            app.i5bot.MoveRobot(q_traj);
            app.GetPos();
            
        end

        % Value changing function: i5_q5Slider
        function i5_q5SliderValueChanging(app, event)
            q5_i5 = pi/180* event.Value;
            steps = 2;
            i5_CurrentJoint = app.i5bot.model.getpos;
            i5_EndJoint = app.UR5bot.model.getpos;
            i5_EndJoint(:,5) = q5_i5;

            q_traj = jtraj(i5_CurrentJoint,i5_EndJoint,steps);
            q5 = lspb(i5_CurrentJoint(5),q5_i5,steps);
            q_traj(:,5) = q5;
            app.i5bot.model.base = transl([0.4,-0.3,-0.03]);

            app.i5bot.MoveRobot(q_traj);
            app.GetPos();
            
        end

        % Value changing function: i5_q6Slider
        function i5_q6SliderValueChanging(app, event)
            q6_i5 = pi/180* event.Value;
            steps = 2;
            i5_CurrentJoint = app.i5bot.model.getpos;
            i5_EndJoint = app.UR5bot.model.getpos;
            i5_EndJoint(:,6) = q6_i5;

            q_traj = jtraj(i5_CurrentJoint,i5_EndJoint,steps);
            q6 = lspb(i5_CurrentJoint(6),q6_i5,steps);
            q_traj(:,6) = q6;
            app.i5bot.model.base = transl([0.4,-0.3,-0.03]);

            app.i5bot.MoveRobot(q_traj);
            app.GetPos();
            
        end

        % Button pushed function: DefaultPhaseButton
        function DefaultPhaseButtonPushed(app, event)
            app.Def_Phase();
        end

        % Button pushed function: ClearEnvironmentButton
        function ClearEnvironmentButtonPushed(app, event)
            clf;
            clear all;
        end

        % Button pushed function: ScenarioRunButton
        function ScenarioRunButtonPushed(app, event)
            
            Assignment2_Scenarios;
        end

        % Value changed function: ESTOPButton
        function ESTOPButtonValueChanged(app, event)
            app.ESTOPButton.Value;
            if app.ESTOPButton.Value == 1
                app.ESTOPButton.BackgroundColor = [1 0 0];
                i = 1; 
                    while i == 1
                pause('on');
                drawnow();
                        if app.ESTOPButton.Value == 0 &&  app.RESTARTButton.Value 
                            i = 0;
                            app.ESTOPButton.BackgroundColor = [0 1 0];
                            pause('off')
                            drawnow();
                        else
                            pause('on');
                            drawnow();
                        end
                    end

            end
        end

        % Value changed function: RESTARTButton
        function RESTARTButtonValueChanged(app, event)
            app.RESTARTButton.Value;
            if (app.RESTARTButton.Value ==1)
                app.RESTARTButton.BackgroundColor = [1 0 0];
            else if (app.RESTARTButton.Value == 0)
                app.RESTARTButton.BackgroundColor = [0 1 0];
            end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UR5JointControlPanel = uipanel(app.UIFigure);
            app.UR5JointControlPanel.Title = 'UR5 Joint Control';
            app.UR5_q1text = uitextarea(app.UR5JointControlPanel);
            app.UR5_q2text = uitextarea(app.UR5JointControlPanel);
            app.UR5_q3text = uitextarea(app.UR5JointControlPanel);
            app.UR5_q4text = uitextarea(app.UR5JointControlPanel);
            app.UR5_q5text = uitextarea(app.UR5JointControlPanel);
            app.UR5_q6text = uitextarea(app.UR5JointControlPanel);
            app.q1SliderLabel = uilabel(app.UR5JointControlPanel);
            app.q1SliderLabel.Text = 'q1';
            app.UR5_q1Slider = uislider(app.UR5JointControlPanel);
            app.UR5_q1Slider.Limits = [-360 360];
            app.UR5_q1Slider.ValueChangingFcn = createCallbackFcn(app, @UR5_q1SliderValueChanging, true);
            app.q2Label = uilabel(app.UR5JointControlPanel);
            app.q2Label.Text = 'q2';
            app.UR5_q2Slider = uislider(app.UR5JointControlPanel);
            app.UR5_q2Slider.Limits = [-360 360];
            app.UR5_q2Slider.ValueChangingFcn = createCallbackFcn(app, @UR5_q2SliderValueChanging, true);
            app.q3Label = uilabel(app.UR5JointControlPanel);
            app.q3Label.Text = 'q3';
            app.UR5_q3Slider = uislider(app.UR5JointControlPanel);
            app.UR5_q3Slider.Limits = [-360 360];
            app.UR5_q3Slider.ValueChangingFcn = createCallbackFcn(app, @UR5_q3SliderValueChanging, true);
            app.q4Label = uilabel(app.UR5JointControlPanel);
            app.q4Label.Text = 'q4';
            app.UR5_q4Slider = uislider(app.UR5JointControlPanel);
            app.UR5_q4Slider.Limits = [-360 360];
            app.UR5_q4Slider.ValueChangingFcn = createCallbackFcn(app, @UR5_q4SliderValueChanging, true);
            app.q5Label = uilabel(app.UR5JointControlPanel);
            app.q5Label.Text = 'q5';
            app.UR5_q5Slider = uislider(app.UR5JointControlPanel);
            app.UR5_q5Slider.Limits = [-360 360];
            app.UR5_q5Slider.ValueChangingFcn = createCallbackFcn(app, @UR5_q5SliderValueChanging, true);
            app.q6Label = uilabel(app.UR5JointControlPanel);
            app.q6Label.Text = 'q6';
            app.UR5_q6Slider = uislider(app.UR5JointControlPanel);
            app.UR5_q6Slider.Limits = [-360 360];
            app.UR5_q6Slider.ValueChangingFcn = createCallbackFcn(app, @UR5_q6SliderValueChanging, true);
            app.i5JointControlPanel = uipanel(app.UIFigure);
            app.i5JointControlPanel.Title = 'i5 Joint Control';
            app.i5_q1text = uitextarea(app.i5JointControlPanel);
            app.i5_q2text = uitextarea(app.i5JointControlPanel);
            app.i5_q3text = uitextarea(app.i5JointControlPanel);
            app.i5_q4text = uitextarea(app.i5JointControlPanel);
            app.i5_q5text = uitextarea(app.i5JointControlPanel);
            app.i5_q6text = uitextarea(app.i5JointControlPanel);
            app.q1Slider_2Label = uilabel(app.i5JointControlPanel);
            app.q1Slider_2Label.Text = 'q1';
            app.i5_q1Slider = uislider(app.i5JointControlPanel);
            app.i5_q1Slider.Limits = [-360 360];
            app.i5_q1Slider.ValueChangingFcn = createCallbackFcn(app, @i5_q1SliderValueChanging, true);
            app.q2Slider_2Label = uilabel(app.i5JointControlPanel);
            app.q2Slider_2Label.Text = 'q2';
            app.i5_q2Slider = uislider(app.i5JointControlPanel);
            app.i5_q2Slider.Limits = [-360 360];
            app.i5_q2Slider.ValueChangingFcn = createCallbackFcn(app, @i5_q2SliderValueChanging, true);
            app.q3Slider_2Label = uilabel(app.i5JointControlPanel);
            app.q3Slider_2Label.Text = 'q3';
            app.i5_q3Slider = uislider(app.i5JointControlPanel);
            app.i5_q3Slider.Limits = [-360 360];
            app.i5_q3Slider.ValueChangingFcn = createCallbackFcn(app, @i5_q3SliderValueChanging, true);
            app.q4Slider_2Label = uilabel(app.i5JointControlPanel);
            app.q4Slider_2Label.Text = 'q4';
            app.i5_q4Slider = uislider(app.i5JointControlPanel);
            app.i5_q4Slider.Limits = [-360 360];
            app.i5_q4Slider.ValueChangingFcn = createCallbackFcn(app, @i5_q4SliderValueChanging, true);
            app.q5Slider_2Label = uilabel(app.i5JointControlPanel);
            app.q5Slider_2Label.Text = 'q5';
            app.i5_q5Slider = uislider(app.i5JointControlPanel);
            app.i5_q5Slider.Limits = [-360 360];
            app.i5_q5Slider.ValueChangingFcn = createCallbackFcn(app, @i5_q5SliderValueChanging, true);
            app.q6Slider_2Label = uilabel(app.i5JointControlPanel);
            app.q6Slider_2Label.Text = 'q6';
            app.i5_q6Slider = uislider(app.i5JointControlPanel);
            app.i5_q6Slider.Limits = [-360 360];
            app.i5_q6Slider.ValueChangingFcn = createCallbackFcn(app, @i5_q6SliderValueChanging, true);
            app.DefaultPhaseButton = uibutton(app.UIFigure, 'push');
            app.DefaultPhaseButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultPhaseButtonPushed, true);
            app.DefaultPhaseButton.Text = 'Default Phase';
            app.ClearEnvironmentButton = uibutton(app.UIFigure, 'push');
            app.ClearEnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @ClearEnvironmentButtonPushed, true);
            app.ClearEnvironmentButton.Text = 'Clear Environment';
            app.ScenarioRunButton = uibutton(app.UIFigure, 'push');
            app.ScenarioRunButton.ButtonPushedFcn = createCallbackFcn(app, @ScenarioRunButtonPushed, true);
            app.ScenarioRunButton.Text = 'Scenario Run';
            app.ESTOPButton = uibutton(app.UIFigure, 'state');
            app.ESTOPButton.ValueChangedFcn = createCallbackFcn(app, @ESTOPButtonValueChanged, true);
            app.ESTOPButton.Icon = 'E-Stop.png';
            app.ESTOPButton.Text = 'E-STOP';
            app.RESTARTButton = uibutton(app.UIFigure, 'state');
            app.RESTARTButton.ValueChangedFcn = createCallbackFcn(app, @RESTARTButtonValueChanged, true);
            app.RESTARTButton.Text = 'RESTART';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Chess_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end