classdef testRobotPlotApp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        Load2RobotsButton         matlab.ui.control.Button
        MakeLoadedPumaMoveButton  matlab.ui.control.Button
        MakeLoadedUR5MoveButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        modelPuma % Where I will put the loaded puma
        modelUR5 % Where I will put the loaded UR5
        qz = [0     0     0     0     0     0];
        qrPuma = [0	pi/2  -pi/2	0	0	0];
        qrUR5 = [pi/4	pi/2  -pi/2	0	0	0];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Load2RobotsButton
        function Load2RobotsButtonPushed(app, event)
            
            axes(app.UIAxes);
            cla(app.UIAxes)
            
            % Make, save and plot some robots            
%             figure('visible','off'); % Makes the figure invisible when loading
            ax = gca;
            mdl_puma560
            app.modelPuma = p560;
            app.modelPuma.base = transl([0.5,0,0]);
            plot(app.modelPuma,app.qrPuma)            
            hold on;            
            app.modelUR5 = UR5;
            app.modelUR5.model.base = transl([-0.5,0,0]);
            app.modelUR5.model.animate(app.qrUR5);
            drawnow();
            
            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            
            % Transfer all the children across to the uiaxes parent EXCEPT
            % the green floors
            while 0 < length(ax.Children)                
                set(ax.Children(1),'Parent',app.UIAxes);
            end
            delete(get(gca,'parent'))
            
            % Scale the axes sensibly
            app.UIAxes.DataAspectRatio = [1,1,1];
            app.UIAxes.View = [45,45];
            
            % Turn off this load button and enable the move button
            app.MakeLoadedPumaMoveButton.Enable = true;
            app.MakeLoadedUR5MoveButton.Enable = true;
            app.Load2RobotsButton.Enable = false;
        end

        % Button pushed function: MakeLoadedPumaMoveButton
        function MakeLoadedPumaMoveButtonPushed(app, event)
            qmatrix = jtraj(app.qrPuma,app.qz,20);          
            for robotStepIndex = 1:size(qmatrix,1)
                
                % Since findobj in animate dosen't work then we have to
                % find the handle ourselves
                handle = [];
                for childIndex = 1:length(app.UIAxes.Children)
                    if strcmp(app.UIAxes.Children(childIndex).Tag,app.modelPuma.name)
                        handle = app.UIAxes.Children(childIndex);
                    end
                end
                
                % Plot it by moving the handle of the robot
                animate(app.modelPuma,qmatrix(robotStepIndex,:),handle) 
                drawnow
            end
        end

        % Button pushed function: MakeLoadedUR5MoveButton
        function MakeLoadedUR5MoveButtonPushed(app, event)
            qmatrix = jtraj(app.qrUR5,app.qz,20);          
            for robotStepIndex = 1:size(qmatrix,1)
                
                % Since findobj in animate dosen't work then we have to
                % find the handle ourselves
                handle = [];
                for childIndex = 1:length(app.UIAxes.Children)
                    if strcmp(app.UIAxes.Children(childIndex).Tag,app.modelUR5.model.name)
                        handle = app.UIAxes.Children(childIndex);
                    end
                end
                
                % Plot it by moving the handle of the robot
                animate(app.modelUR5.model,qmatrix(robotStepIndex,:),handle) 
                drawnow
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Load2RobotsButton = uibutton(app.UIFigure, 'push');
            app.Load2RobotsButton.ButtonPushedFcn = createCallbackFcn(app, @Load2RobotsButtonPushed, true);
            app.Load2RobotsButton.Text = 'Load 2 Robots';
            app.MakeLoadedPumaMoveButton = uibutton(app.UIFigure, 'push');
            app.MakeLoadedPumaMoveButton.ButtonPushedFcn = createCallbackFcn(app, @MakeLoadedPumaMoveButtonPushed, true);
            app.MakeLoadedPumaMoveButton.Enable = 'off';
            app.MakeLoadedPumaMoveButton.Text = 'Make Loaded Puma Move';
            app.MakeLoadedUR5MoveButton = uibutton(app.UIFigure, 'push');
            app.MakeLoadedUR5MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MakeLoadedUR5MoveButtonPushed, true);
            app.MakeLoadedUR5MoveButton.Enable = 'off';
            app.MakeLoadedUR5MoveButton.Text = 'Make Loaded UR5 Move';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testRobotPlotApp1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end